classdef cniQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadQAdataMenu        matlab.ui.container.Menu
        SaveQAdataMenu        matlab.ui.container.Menu
        QuitMenu              matlab.ui.container.Menu
        FlywheelMenu          matlab.ui.container.Menu
        SigninMenu            matlab.ui.container.Menu
        ProjectlabelMenu      matlab.ui.container.Menu
        ToolsMenu             matlab.ui.container.Menu
        App2workspaceMenu     matlab.ui.container.Menu
        ProjectnameMenu       matlab.ui.container.Menu
        PlotMenu              matlab.ui.container.Menu
        SelectdataPanel       matlab.ui.container.Panel
        RetrieveQAButton      matlab.ui.control.Button
        SinceDatePickerLabel  matlab.ui.control.Label
        SinceDatePicker       matlab.ui.control.DatePicker
        CNIImagequalityLabel  matlab.ui.control.Label
        UntilDatePickerLabel  matlab.ui.control.Label
        UntilDatePicker       matlab.ui.control.DatePicker
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cni  =[]; % scitran channel to the cni site
    end
    
    properties (Access = public)
        qaData = [];    % Struct with all the qaData
        qaProject = []; % Flywheel project container
    end
    
    methods (Access = public)
        
        function [acq1, acq2] = qaAcqSort(app,varargin)
            %% Now we could pull out the variables we want
            
            % We need a way to generalize these names in the UI
            acqNames = {'BOLD EPI Ax','Ax EPI'};
            stPrint(app.qaData,'acquisition');
            
            acq1 = stSelect(app.qaData,'acquisition',acqNames{1});
            acq2 = stSelect(app.qaData,'acquisition',acqNames{2});
            
        end
        
        function qaPlot(~,acq,varargin)
            % Not sure how to organize the plotting.  More UI elements
            % needed.
            
            % We need to select the figure
            mrvNewGraphWin;
            s = zeros(numel(acq));
            d = zeros(numel(acq));
            for ii=1:numel(acq)
                s(ii) = str2double(acq{ii}.sfnr_center);
                d(ii) = acq{ii}.created;
            end
            plot(d,s,'LineWidth',2)
            grid on
            % title(acqNames{1});
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(which('scitran'))
                warning('No scitran on your path. You can load and plot local data, but not interact with Flywheel.');
            end
            if isempty(app.cni)
                % Create the channel to Flywheel
                app.cni = scitran('cni');
            end
            % We only allow interaction with the QA project for now.  We
            % may generalize in the future.
            app.qaProject = app.cni.lookup('cni/qa');
        end

        % Button pushed function: RetrieveQAButton
        function qaRetrieve(app, event)
            % We should check whether scitran is on the path and qaProject
            % exists.  If not, this button should be disabled.
            
            since = sprintf('created>%s',app.SinceDatePicker.Value);
            qaSessions = app.qaProject.sessions.find(since);
            fprintf('Found %d sessions since %s\n',numel(qaSessions),since);
            
            qq = 1;
            
            for ss=1:numel(qaSessions)
                
                % Get the analyses
                qaAnalyses = qaSessions{ss}.analyses();
                if ~isempty(qaAnalyses)
                    
                    qaAnalyses = stSelect(qaAnalyses,'label','cni-tsnr');
                    if ~isempty(qaAnalyses)
                        for aa = 1:numel(qaAnalyses)
                            % Find the result file
                            thisFile = stSelect(qaAnalyses{aa}.files,'name','result');
                            if isempty(thisFile)
                                fprintf('No result.json file found\n');
                                stPrint(qaAnalyses{aa}.files,'name');
                            else
                                fprintf('Analyses with a result file found in session %d\n',ss);
                                
                                % Download the file and read its contents
                                thisFile{1}.download('result.json');
                                tmp = jsonread('result.json');
                                tmp.created = qaAnalyses{aa}.created;
                                
                                %% Find which acquisition has the input file from the analyses
                                
                                % The acquisition label should be the same when we plot the snr or sfnr.
                                fileid = qaAnalyses{aa}.inputs{1}.id;
                                thisAcq = app.cni.search('acquisition','fileid',fileid,'container',true);
                                if ~isempty(thisAcq)
                                    tmp.acquisition =  thisAcq{1}.label;
                                else
                                    fprintf('No acquisition found for input file on analysis %d\n',aa);
                                    tmp.acquisition = 'Unknown acq';
                                end
                                app.qaData{qq} = tmp;
                                qq = qq+1;
                            end
                        end
                    end
                end
            end
        end

        % Menu selected function: App2workspaceMenu
        function app2ws(app, event)
            fprintf('Assigning the variable cniQA into the work space.\n');
            assignin('base','cniQA',app);
        end

        % Menu selected function: LoadQAdataMenu
        function qaLoad(app, event)
            % Perhaps we should save the path to the file as well.
            % We just find the file and load the qaData variable.
            
            % User selects the file
            [filename,filepath] = uigetfile('*.mat');  %open a mat file
            try
                tmp = load(fullfile(filepath,filename),'qaData');
            catch
                warning('File does not contain qaData variable');
                return;
            end
            app.qaData = tmp.qaData;
            
            % Now set the min and max dates in the date picker
            since = app.qaData{1}.created;
            until = app.qaData{1}.created;
            for ii=1:numel(app.qaData)
                since = min(app.qaData{ii}.created, since);
                until = max(app.qaData{ii}.created, until);
            end
            app.SinceDatePicker.Value = since;
            app.UntilDatePicker.Value = until;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadQAdataMenu = uimenu(app.FileMenu);
            app.LoadQAdataMenu.MenuSelectedFcn = createCallbackFcn(app, @qaLoad, true);
            app.LoadQAdataMenu.Text = 'Load QA data';
            app.SaveQAdataMenu = uimenu(app.FileMenu);
            app.SaveQAdataMenu.Text = 'Save QA data';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.FlywheelMenu = uimenu(app.UIFigure);
            app.FlywheelMenu.Text = 'Flywheel';
            app.SigninMenu = uimenu(app.FlywheelMenu);
            app.SigninMenu.Text = 'Sign in';
            app.ProjectlabelMenu = uimenu(app.FlywheelMenu);
            app.ProjectlabelMenu.Text = 'Project label';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.App2workspaceMenu = uimenu(app.ToolsMenu);
            app.App2workspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @app2ws, true);
            app.App2workspaceMenu.Text = 'App 2 workspace';
            app.ProjectnameMenu = uimenu(app.ToolsMenu);
            app.ProjectnameMenu.Text = 'Project name';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.SelectdataPanel = uipanel(app.UIFigure);
            app.SelectdataPanel.Title = 'Select data';
            app.RetrieveQAButton = uibutton(app.SelectdataPanel, 'push');
            app.RetrieveQAButton.ButtonPushedFcn = createCallbackFcn(app, @qaRetrieve, true);
            app.RetrieveQAButton.Text = 'Retrieve QA';
            app.SinceDatePickerLabel = uilabel(app.UIFigure);
            app.SinceDatePickerLabel.Text = 'Since';
            app.SinceDatePicker = uidatepicker(app.UIFigure);
            app.SinceDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.CNIImagequalityLabel = uilabel(app.UIFigure);
            app.CNIImagequalityLabel.Text = 'CNI Image quality';
            app.UntilDatePickerLabel = uilabel(app.UIFigure);
            app.UntilDatePickerLabel.Text = 'Until';
            app.UntilDatePicker = uidatepicker(app.UIFigure);
            app.UntilDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scan Type')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'SNR')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cniQA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end